classdef PlantCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SingleFileTab               matlab.ui.container.Tab
        BrowseButton                matlab.ui.control.Button
        spectrumAxes                matlab.ui.control.UIAxes
        MeasurmentsPanel            matlab.ui.container.Panel
        PPFRadiantEditFieldLabel    matlab.ui.control.Label
        PPFRadiant                  matlab.ui.control.NumericEditField
        WattsLabel                  matlab.ui.control.Label
        MicromolessecLabel          matlab.ui.control.Label
        PPFQuantaEditFieldLabel     matlab.ui.control.Label
        PPFQuanta                   matlab.ui.control.NumericEditField
        WattsLabel_2                matlab.ui.control.Label
        MicromolessecLabel_2        matlab.ui.control.Label
        YPFRadiantEditFieldLabel    matlab.ui.control.Label
        YPFRadiant                  matlab.ui.control.NumericEditField
        YPFQuantaEditFieldLabel     matlab.ui.control.Label
        YPFQuanta                   matlab.ui.control.NumericEditField
        LuxLabel_2                  matlab.ui.control.Label
        WattsLabel_4                matlab.ui.control.Label
        MicromolessecLabel_4        matlab.ui.control.Label
        LuxLabel                    matlab.ui.control.Label
        LuxEdit                     matlab.ui.control.NumericEditField
        RCRLabel                    matlab.ui.control.Label
        YPFRadiant_2                matlab.ui.control.NumericEditField
        PSSLabel                    matlab.ui.control.Label
        YPFQuanta_2                 matlab.ui.control.NumericEditField
        ChromaticityEditFieldLabel  matlab.ui.control.Label
        Chromaticity                matlab.ui.control.EditField
        MultiplierLable             matlab.ui.control.Label
        FilepathEditFieldLabel      matlab.ui.control.Label
        Filepath                    matlab.ui.control.EditField
        GroupFileTab                matlab.ui.container.Tab
        ToBeDoneLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        spectrum % Description
        McCreeFromGraph = load('McCree1972.txt');
        h = 6.63e-34; % Joule seconds, Planck's constant
        c = 3.00e8; % meter/second
        Avo = 6.02e23; % Avogardo constant
        PPF_W
        PPF_mole
        YPF_W
        YPF_mole
        Lux
        xChrom
        yChrom
        Tc
    end
    
    methods (Access = public)
        
        function results = updatePlot(app)
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            max_specFlux = max(specFlux);
            app.MultiplierLable.Text = ['Multiplier = ',num2str(max_specFlux,'%10.4e\n'),' (W/nm)'];
            normalSpecFlux = specFlux/max_specFlux;
            plot(app.spectrumAxes,wave,normalSpecFlux);
            xlabel(app.spectrumAxes,'Wavelength (nm)');
            ylabel(app.spectrumAxes,'Normalized Spectral Flux');
            updateMeasurments(app);
        end
         
        
        function results = updateMeasurments(app)
            %% Calculate Measurments
            wave = app.spectrum(:,1);
            specFlux = app.spectrum(:,2);
            McCreePhoton = interp1(app.McCreeFromGraph(:,1),app.McCreeFromGraph(:,2),wave,'linear',0.0);
            McCreeWatt = McCreePhoton.*((wave*1e-9)/(app.h*app.c*app.Avo)); % convert growth/photon to growth/joule
            McCreeWatt = McCreeWatt/max(McCreeWatt);
            q1 = find(wave>=400,1,'first');
            q2 = find(wave<=700,1,'last');
            app.PPF_W = trapz(wave(q1:q2),specFlux(q1:q2)); % Watts
            app.PPF_mole = 1e6*trapz(wave(q1:q2),specFlux(q1:q2).*(wave(q1:q2)*1e-9))/(app.h*app.c*app.Avo); % micromoles/s
            app.YPF_W = trapz(wave,specFlux.*McCreeWatt); % Watts
            app.YPF_mole = 1e6*trapz(wave,McCreePhoton.*specFlux.*(wave*1e-9))/(app.h*app.c*app.Avo); % micromoles/s
            [app.Lux,app.xChrom,app.yChrom] = Lxy23Sep05([wave,specFlux]);
            app.Tc = CCT23Sep05(app.spectrum);
            
            %% Show Measurments
            app.PPFRadiant.Value = app.PPF_W;
            app.PPFQuanta.Value = app.PPF_mole;
            app.YPFRadiant.Value = app.YPF_W;
            app.YPFQuanta.Value = app.YPF_mole;
            app.LuxEdit.Value = app.Lux;
            app.Chromaticity.Value = ['(',num2str(app.xChrom,3),',',num2str(app.yChrom,3),')'];
            
            
        end
        
        
    end
    

    methods (Access = private)

        % Callback function
        function BrowseButtonPushed(app, event)
            [FileName,PathName,FilterIndex] = uigetfile({'*.*'},'File Selector');
            if FilterIndex == 1
                app.Filepath.Value = fullfile(PathName,FileName);
                app.spectrum = load(fullfile(PathName,FileName));
                updatePlot(app);
            end
        end

        % Callback function
        function FilepathValueChanged(app, event)
            value = app.Filepath.Value;
            if 2 == exist(value,'file')
                app.spectrum = load(value);
                updatePlot(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleFileTab = uitab(app.TabGroup);
            app.SingleFileTab.Title = 'Single File';
            app.SingleFileTab.Units = 'pixels';
            app.BrowseButton = uibutton(app.SingleFileTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.spectrumAxes = uiaxes(app.SingleFileTab);
            title(app.spectrumAxes, 'Spectrum');
            xlabel(app.spectrumAxes, 'Wavelength (nm)');
            ylabel(app.spectrumAxes, 'Power');
            app.MeasurmentsPanel = uipanel(app.SingleFileTab);
            app.MeasurmentsPanel.Title = 'Measurments';
            app.PPFRadiantEditFieldLabel = uilabel(app.MeasurmentsPanel);
            app.PPFRadiantEditFieldLabel.Text = 'PPF Radiant';
            app.PPFRadiant = uieditfield(app.MeasurmentsPanel, 'numeric');
            app.PPFRadiant.ValueDisplayFormat = '%5.4f';
            app.PPFRadiant.Editable = 'off';
            app.WattsLabel = uilabel(app.MeasurmentsPanel);
            app.WattsLabel.Text = 'Watts';
            app.MicromolessecLabel = uilabel(app.MeasurmentsPanel);
            app.MicromolessecLabel.Text = 'Micromoles/sec';
            app.PPFQuantaEditFieldLabel = uilabel(app.MeasurmentsPanel);
            app.PPFQuantaEditFieldLabel.Text = 'PPF Quanta';
            app.PPFQuanta = uieditfield(app.MeasurmentsPanel, 'numeric');
            app.PPFQuanta.ValueDisplayFormat = '%5.4f';
            app.PPFQuanta.Editable = 'off';
            app.WattsLabel_2 = uilabel(app.MeasurmentsPanel);
            app.WattsLabel_2.Text = 'Watts';
            app.MicromolessecLabel_2 = uilabel(app.MeasurmentsPanel);
            app.MicromolessecLabel_2.Text = 'Micromoles/sec';
            app.YPFRadiantEditFieldLabel = uilabel(app.MeasurmentsPanel);
            app.YPFRadiantEditFieldLabel.Text = 'YPF Radiant';
            app.YPFRadiant = uieditfield(app.MeasurmentsPanel, 'numeric');
            app.YPFRadiant.ValueDisplayFormat = '%5.4f';
            app.YPFRadiant.Editable = 'off';
            app.YPFQuantaEditFieldLabel = uilabel(app.MeasurmentsPanel);
            app.YPFQuantaEditFieldLabel.Text = 'YPF Quanta';
            app.YPFQuanta = uieditfield(app.MeasurmentsPanel, 'numeric');
            app.YPFQuanta.ValueDisplayFormat = '%5.4f';
            app.YPFQuanta.Editable = 'off';
            app.LuxLabel_2 = uilabel(app.MeasurmentsPanel);
            app.LuxLabel_2.Text = 'Lux';
            app.WattsLabel_4 = uilabel(app.MeasurmentsPanel);
            app.WattsLabel_4.Text = 'Watts';
            app.MicromolessecLabel_4 = uilabel(app.MeasurmentsPanel);
            app.MicromolessecLabel_4.Text = 'Micromoles/sec';
            app.LuxLabel = uilabel(app.MeasurmentsPanel);
            app.LuxLabel.Text = 'Lux';
            app.LuxEdit = uieditfield(app.MeasurmentsPanel, 'numeric');
            app.LuxEdit.ValueDisplayFormat = '%5.4f';
            app.LuxEdit.Editable = 'off';
            app.RCRLabel = uilabel(app.MeasurmentsPanel);
            app.RCRLabel.Text = 'RCR';
            app.YPFRadiant_2 = uieditfield(app.MeasurmentsPanel, 'numeric');
            app.YPFRadiant_2.ValueDisplayFormat = '%5.4f';
            app.YPFRadiant_2.Editable = 'off';
            app.PSSLabel = uilabel(app.MeasurmentsPanel);
            app.PSSLabel.Text = 'PSS';
            app.YPFQuanta_2 = uieditfield(app.MeasurmentsPanel, 'numeric');
            app.YPFQuanta_2.ValueDisplayFormat = '%5.4f';
            app.YPFQuanta_2.Editable = 'off';
            app.ChromaticityEditFieldLabel = uilabel(app.MeasurmentsPanel);
            app.ChromaticityEditFieldLabel.Text = 'Chromaticity';
            app.Chromaticity = uieditfield(app.MeasurmentsPanel, 'text');
            app.Chromaticity.Editable = 'off';
            app.MultiplierLable = uilabel(app.SingleFileTab);
            app.MultiplierLable.Text = 'Multiplier = N/A';
            app.FilepathEditFieldLabel = uilabel(app.SingleFileTab);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.Filepath = uieditfield(app.SingleFileTab, 'text');
            app.Filepath.ValueChangedFcn = createCallbackFcn(app, @FilepathValueChanged, true);
            app.Filepath.Value = 'File Name...';
            app.GroupFileTab = uitab(app.TabGroup);
            app.GroupFileTab.Title = 'Group File';
            app.GroupFileTab.Units = 'pixels';
            app.ToBeDoneLabel = uilabel(app.GroupFileTab);
            app.ToBeDoneLabel.Text = 'To Be Done';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlantCalculator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end